classdef FMCW_GUIrev1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SystemDelayCH2EditField       matlab.ui.control.NumericEditField
        SystemDelayCH2EditFieldLabel  matlab.ui.control.Label
        SystemDelayCH1EditField       matlab.ui.control.NumericEditField
        SystemDelayCH1EditFieldLabel  matlab.ui.control.Label
        StartIndexCH1EditField        matlab.ui.control.NumericEditField
        StartIndexCH1EditFieldLabel   matlab.ui.control.Label
        StartIndexCH2EditField        matlab.ui.control.NumericEditField
        StartIndexCH2EditFieldLabel   matlab.ui.control.Label
        DelayEditField_1              matlab.ui.control.NumericEditField
        DelayEditField_4Label         matlab.ui.control.Label
        RangeEditField_3              matlab.ui.control.NumericEditField
        RangeEditField_3Label         matlab.ui.control.Label
        DelayEditField_3              matlab.ui.control.NumericEditField
        DelayEditField_3Label         matlab.ui.control.Label
        AverageLabel                  matlab.ui.control.Label
        RangeEditField_2              matlab.ui.control.NumericEditField
        RangeEditField_2Label         matlab.ui.control.Label
        DelayEditField_2              matlab.ui.control.NumericEditField
        DelayEditField_2Label         matlab.ui.control.Label
        FrequencyDomainLabel          matlab.ui.control.Label
        TimeDomainLabel               matlab.ui.control.Label
        RangeEditField_1              matlab.ui.control.NumericEditField
        RangeEditFieldLabel           matlab.ui.control.Label
        Step4Label                    matlab.ui.control.Label
        Step3Label                    matlab.ui.control.Label
        Step2Label                    matlab.ui.control.Label
        Step1Label                    matlab.ui.control.Label
        PostProcessButton             matlab.ui.control.Button
        RunSlaveBtn                   matlab.ui.control.Button
        RunMasterBtn                  matlab.ui.control.Button
        GenerateBtn                   matlab.ui.control.Button
        RxFFTAxes2                    matlab.ui.control.UIAxes
        RxFFTAxes1                    matlab.ui.control.UIAxes
        TxFFTAxes2                    matlab.ui.control.UIAxes
        TxFFTAxes1                    matlab.ui.control.UIAxes
        HeatmapAxes                   matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateBtn
        function GenerateBtnButtonPushed(app, event)
            transmitFile = 'R:\Temp\transmit.sc16q11';
            generateChirpWaveform(transmitFile);
            uialert(app.UIFigure, 'Waveform generated successfully.', 'Success');
        end

        % Button pushed function: RunMasterBtn
        function RunMasterBtnButtonPushed(app, event)
            try
                runMasterScript();
                uialert(app.UIFigure, 'MASTER script executed successfully.', 'Success');
            catch ME
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Execution Failed');
            end
        end

        % Button pushed function: RunSlaveBtn
        function RunSlaveBtnButtonPushed(app, event)
            try
                runSlaveScript();
                uialert(app.UIFigure, 'SLAVE script executed successfully.', 'Success');
            catch ME
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Execution Failed');
            end
        end

        % Button pushed function: PostProcessButton
        function PostProcessButtonPushed(app, event)
            try
                txFile = 'R:\Temp\transmit12.sc16q11';
                rxFile = 'R:\Temp\receive12.sc16q11';
        
                % Read sample delay values from GUI input fields
                systemDelaySamplesCH1 = app.SystemDelayCH1EditField.Value;
                systemDelaySamplesCH2 = app.SystemDelayCH2EditField.Value;

                [td, range_td_ft, tau_from_fb, range_fb_ft, range_ft, ...
                  startIdxCH1_Coarse, startIdxCH2_Coarse, ...
                  systemDelaySamplesCH1, systemDelaySamplesCH2, ...
                  thetaScan, range_axis, TargetMap, ...
                  f, txFFT1, txFFT2, rxFFT1, rxFFT2, ...
                  sti, sri, Fs, frequencyStart, frequencyEnd, fo] = ...
                  processRadarData(txFile, rxFile, systemDelaySamplesCH1, systemDelaySamplesCH2)


                % Update Delay & Range
                app.DelayEditField_1.Value     = td * 1e9;
                app.RangeEditField_1.Value     = range_td_ft;
                app.DelayEditField_2.Value     = tau_from_fb * 1e9;
                app.RangeEditField_2.Value     = range_fb_ft;
                app.DelayEditField_3.Value     = (td + tau_from_fb) / 2 * 1e9;
                app.RangeEditField_3.Value     = range_ft;
        
                % Update Start Indices and System Delay
                app.StartIndexCH1EditField.Value = startIdxCH1_Coarse;
                app.StartIndexCH2EditField.Value = startIdxCH2_Coarse;
                app.SystemDelayCH1EditField.Value = systemDelaySamplesCH1;
                app.SystemDelayCH2EditField.Value = systemDelaySamplesCH2;

                N = length(sti(:,1));                                      % Number of samples
                X = fftshift(abs(fft(sti(:,1), N)));                       % Compute FFT
                f = linspace(-Fs/2, Fs/2, N) + frequencyStart;             % Frequency axis (Hz)
                
                plot(app.TxFFTAxes1, f / 1e9, X / max(X));                 % Normalize and plot in GHz
                xlabel(app.TxFFTAxes1, 'Frequency (GHz)');
                ylabel(app.TxFFTAxes1, 'Normalized Magnitude');
                title(app.TxFFTAxes1, 'FFT Spectrum - TX1');
                xlim(app.TxFFTAxes1, [2.435 2.465]);                       % Optional: zoom to bandwidth
                ylim(app.TxFFTAxes1, [0 1]);
                grid(app.TxFFTAxes1, 'on');

                N = length(sti(:,2));                                      % Number of samples
                X = fftshift(abs(fft(sti(:,2), N)));                       % Compute FFT
                f = linspace(-Fs/2, Fs/2, N) + frequencyStart;             % Frequency axis (Hz)
                
                plot(app.TxFFTAxes2, f / 1e9, X / max(X));                 % Normalize and plot in GHz
                xlabel(app.TxFFTAxes2, 'Frequency (GHz)');
                ylabel(app.TxFFTAxes2, 'Normalized Magnitude');
                title(app.TxFFTAxes2, 'FFT Spectrum - TX1');
                xlim(app.TxFFTAxes2, [2.435 2.465]);                       % Optional: zoom to bandwidth
                ylim(app.TxFFTAxes2, [0 1]);
                grid(app.TxFFTAxes2, 'on');

                 N = length(sri(:,1));                                      % Number of samples
                X = fftshift(abs(fft(sri(:,1), N)));                       % Compute FFT
                f = linspace(-Fs/2, Fs/2, N) + frequencyStart;             % Frequency axis (Hz)
                
                plot(app.RxFFTAxes1, f / 1e9, X / max(X));                 % Normalize and plot in GHz
                xlabel(app.RxFFTAxes1, 'Frequency (GHz)');
                ylabel(app.RxFFTAxes1, 'Normalized Magnitude');
                title(app.RxFFTAxes1, 'FFT Spectrum - TX1');
                xlim(app.RxFFTAxes1, [2.435 2.465]);                       % Optional: zoom to bandwidth
                ylim(app.RxFFTAxes1, [0 1]);
                grid(app.RxFFTAxes1, 'on');

                N = length(sri(:,2));                                      % Number of samples
                X = fftshift(abs(fft(sri(:,2), N)));                       % Compute FFT
                f = linspace(-Fs/2, Fs/2, N) + frequencyStart;             % Frequency axis (Hz)
                
                plot(app.RxFFTAxes2, f / 1e9, X / max(X));                 % Normalize and plot in GHz
                xlabel(app.RxFFTAxes2, 'Frequency (GHz)');
                ylabel(app.RxFFTAxes2, 'Normalized Magnitude');
                title(app.RxFFTAxes2, 'FFT Spectrum - TX1');
                xlim(app.RxFFTAxes2, [2.435 2.465]);                       % Optional: zoom to bandwidth
                ylim(app.RxFFTAxes2, [0 1]);
                grid(app.RxFFTAxes2, 'on');

                        % --- PLOT HEATMAP ---
                imagesc(app.HeatmapAxes, thetaScan, range_axis, TargetMap);
                xlabel(app.HeatmapAxes, 'Angle of Arrival (degrees)');
                ylabel(app.HeatmapAxes, 'Range (ft)');
                title(app.HeatmapAxes, 'Range vs AoA Heatmap');
                colormap(app.HeatmapAxes, 'jet');
                colorbar(app.HeatmapAxes);
                axis(app.HeatmapAxes, 'xy');
                app.HeatmapAxes.CLim = [0 1];

                uialert(app.UIFigure, 'Post-processing completed successfully.', 'Success');
            catch ME
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Post Processing Error');
            end
            run('Prototype4PostProcessing_FineDelayRev3.m');  % Run the standalone post-processing script
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HeatmapAxes = uiaxes(app.UIFigure);
            title(app.HeatmapAxes, 'Title')
            xlabel(app.HeatmapAxes, 'X')
            ylabel(app.HeatmapAxes, 'Y')
            zlabel(app.HeatmapAxes, 'Z')
            app.TxFFTAxes1 = uiaxes(app.UIFigure);
            title(app.TxFFTAxes1, 'Title')
            xlabel(app.TxFFTAxes1, 'X')
            ylabel(app.TxFFTAxes1, 'Y')
            zlabel(app.TxFFTAxes1, 'Z')
            app.TxFFTAxes2 = uiaxes(app.UIFigure);
            title(app.TxFFTAxes2, 'Title')
            xlabel(app.TxFFTAxes2, 'X')
            ylabel(app.TxFFTAxes2, 'Y')
            zlabel(app.TxFFTAxes2, 'Z')
            app.RxFFTAxes1 = uiaxes(app.UIFigure);
            title(app.RxFFTAxes1, 'Title')
            xlabel(app.RxFFTAxes1, 'X')
            ylabel(app.RxFFTAxes1, 'Y')
            zlabel(app.RxFFTAxes1, 'Z')
            app.RxFFTAxes2 = uiaxes(app.UIFigure);
            title(app.RxFFTAxes2, 'Title')
            xlabel(app.RxFFTAxes2, 'X')
            ylabel(app.RxFFTAxes2, 'Y')
            zlabel(app.RxFFTAxes2, 'Z')
            app.GenerateBtn = uibutton(app.UIFigure, 'push');
            app.GenerateBtn.ButtonPushedFcn = createCallbackFcn(app, @GenerateBtnButtonPushed, true);
            app.GenerateBtn.Text = 'Generate Chirp';
            app.RunMasterBtn = uibutton(app.UIFigure, 'push');
            app.RunMasterBtn.ButtonPushedFcn = createCallbackFcn(app, @RunMasterBtnButtonPushed, true);
            app.RunMasterBtn.Text = 'Master';
            app.RunSlaveBtn = uibutton(app.UIFigure, 'push');
            app.RunSlaveBtn.ButtonPushedFcn = createCallbackFcn(app, @RunSlaveBtnButtonPushed, true);
            app.RunSlaveBtn.Text = 'Slave';
            app.PostProcessButton = uibutton(app.UIFigure, 'push');
            app.PostProcessButton.ButtonPushedFcn = createCallbackFcn(app, @PostProcessButtonPushed, true);
            app.PostProcessButton.Text = 'Post Process';
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step 1';
            app.Step2Label = uilabel(app.UIFigure);
            app.Step2Label.Text = 'Step 2';
            app.Step3Label = uilabel(app.UIFigure);
            app.Step3Label.Text = 'Step 3';
            app.Step4Label = uilabel(app.UIFigure);
            app.Step4Label.Text = 'Step 4';
            app.RangeEditFieldLabel = uilabel(app.UIFigure);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.TimeDomainLabel = uilabel(app.UIFigure);
            app.TimeDomainLabel.Text = 'Time Domain';
            app.FrequencyDomainLabel = uilabel(app.UIFigure);
            app.FrequencyDomainLabel.Text = 'Frequency Domain';
            app.DelayEditField_2Label = uilabel(app.UIFigure);
            app.DelayEditField_2Label.Text = 'Delay';
            app.DelayEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RangeEditField_2Label = uilabel(app.UIFigure);
            app.RangeEditField_2Label.Text = 'Range';
            app.RangeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AverageLabel = uilabel(app.UIFigure);
            app.AverageLabel.Text = 'Average';
            app.DelayEditField_3Label = uilabel(app.UIFigure);
            app.DelayEditField_3Label.Text = 'Delay';
            app.DelayEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.RangeEditField_3Label = uilabel(app.UIFigure);
            app.RangeEditField_3Label.Text = 'Range';
            app.RangeEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField_4Label = uilabel(app.UIFigure);
            app.DelayEditField_4Label.Text = 'Delay';
            app.DelayEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.StartIndexCH2EditFieldLabel = uilabel(app.UIFigure);
            app.StartIndexCH2EditFieldLabel.Text = 'Start Index CH2';
            app.StartIndexCH2EditField = uieditfield(app.UIFigure, 'numeric');
            app.StartIndexCH1EditFieldLabel = uilabel(app.UIFigure);
            app.StartIndexCH1EditFieldLabel.Text = 'Start Index CH1';
            app.StartIndexCH1EditField = uieditfield(app.UIFigure, 'numeric');
            app.SystemDelayCH1EditFieldLabel = uilabel(app.UIFigure);
            app.SystemDelayCH1EditFieldLabel.Text = 'System Delay CH1';
            app.SystemDelayCH1EditField = uieditfield(app.UIFigure, 'numeric');
            app.SystemDelayCH2EditFieldLabel = uilabel(app.UIFigure);
            app.SystemDelayCH2EditFieldLabel.Text = 'System Delay CH2';
            app.SystemDelayCH2EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FMCW_GUIrev1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end